classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        HelpTab                      matlab.ui.container.Tab
        HTML                         matlab.ui.control.HTML
        OriginalImageTab             matlab.ui.container.Tab
        IsometricColorTab            matlab.ui.container.Tab
        ImageLinesTab                matlab.ui.container.Tab
        CorrelationValueTab          matlab.ui.container.Tab
        ProjectedImageTab            matlab.ui.container.Tab
        ProjectedColorTab            matlab.ui.container.Tab
        DPlotTab                     matlab.ui.container.Tab
        CompareListListBoxLabel      matlab.ui.control.Label
        CompareListListBox           matlab.ui.control.ListBox
        AddforComparisonButton       matlab.ui.control.Button
        CompareButton                matlab.ui.control.Button
        ControlsPanel                matlab.ui.container.Panel
        btn                          matlab.ui.control.Button
        oriCheckBox                  matlab.ui.control.CheckBox
        isometricCheckBox            matlab.ui.control.CheckBox
        imageLinesCheckBox           matlab.ui.control.CheckBox
        ChooseFileButton             matlab.ui.control.Button
        corrValueCheckBox            matlab.ui.control.CheckBox
        showallCheckBox              matlab.ui.control.CheckBox
        projectImgCheckBox           matlab.ui.control.CheckBox
        projectColorCheckBox         matlab.ui.control.CheckBox
        plot3dCheckBox               matlab.ui.control.CheckBox
        RubikLocationEditFieldLabel  matlab.ui.control.Label
        loc                          matlab.ui.control.EditField
        ClearListButton              matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startUp(app)
            url = "html/help.html";
            html = fileread(url);
            app.HTML.HTMLSource = html;
            
            propName = 'tabId';
            pltName = 'plot';
            
            tabList.tabHelp = app.HelpTab;
            setappdata(tabList.tabHelp, propName, 1);
            
            tabList.tabOri = app.OriginalImageTab;
            tabList.tabOri.Parent = [];
            setappdata(tabList.tabOri, propName, 2);
            
            tabList.tabIso = app.IsometricColorTab;
            tabList.tabIso.Parent = [];
            setappdata(tabList.tabIso, propName, 3);
            
            tabList.tabImgLn = app.ImageLinesTab;
            tabList.tabImgLn.Parent = [];
            setappdata(tabList.tabImgLn, propName, 4);
            
            tabList.tabCorrV = app.CorrelationValueTab;
            tabList.tabCorrV.Parent = [];
            setappdata(tabList.tabCorrV, propName, 5);
            
            tabList.tabPrjI = app.ProjectedImageTab;
            tabList.tabPrjI.Parent = [];
            setappdata(tabList.tabPrjI, propName, 6);
            
            tabList.tabPrjC = app.ProjectedColorTab;
            tabList.tabPrjC.Parent = [];
            setappdata(tabList.tabPrjC, propName, 7);
            
            tabList.tab3dPlt = app.DPlotTab;
            tabList.tab3dPlt.Parent = [];
            setappdata(tabList.tab3dPlt, propName, 8);
            
            setappdata(app.TabGroup, 'tabList', tabList);
            
            setappdata(app.CompareListListBox, 'cnt', 1);
        end

        % Button pushed function: btn
        function buttonCallbacks(app, event)
            %directory handler
            dir = getappdata(app.ControlsPanel, 'urls');
            setappdata(app.UIFigure, 'urlNow', dir);
            if isa(dir, 'double') || dir == ""
                msgbox('Choose any file first');
                return;
            end
            
            %checkbox handler
            checkBoxVal.oriCheckBox = app.oriCheckBox.Value;
            checkBoxVal.isometricCheckBox = app.isometricCheckBox.Value;
            checkBoxVal.imageLinesCheckBox = app.imageLinesCheckBox.Value;
            checkBoxVal.corrValueCheckBox = app.corrValueCheckBox.Value;
            checkBoxVal.projectColorCheckBox = app.projectColorCheckBox.Value;
            checkBoxVal.projectImgCheckBox = app.projectImgCheckBox.Value;
            checkBoxVal.plot3dCheckBox = app.plot3dCheckBox.Value;
            checkBoxVal.cnt = 0;
            checkBoxVal.cnt = checkBoxVal.cnt + app.oriCheckBox.Value;
            checkBoxVal.cnt = checkBoxVal.cnt + app.isometricCheckBox.Value;
            checkBoxVal.cnt = checkBoxVal.cnt + app.imageLinesCheckBox.Value;
            checkBoxVal.cnt = checkBoxVal.cnt + app.corrValueCheckBox.Value;
            checkBoxVal.cnt = checkBoxVal.cnt + app.projectColorCheckBox.Value;
            checkBoxVal.cnt = checkBoxVal.cnt + app.projectImgCheckBox.Value;
            checkBoxVal.cnt = checkBoxVal.cnt + app.plot3dCheckBox.Value;
            
            if checkBoxVal.cnt == 0
                msgbox('Choose any show option first');
                return;
            end
            
            processImage(dir, checkBoxVal, app.TabGroup, app.UIFigure);
        end

        % Button pushed function: ChooseFileButton
        function chooseFile(app, event)
            [fileName, path] = uigetfile('*.jpg;*.png;*.bmp');
            figure(app.UIFigure);
            
            app.loc.Value = fileName;
            setappdata(app.ControlsPanel, 'urls', fullfile(path, fileName));
        end

        % Value changed function: showallCheckBox
        function checkedAll(app, event)
            value = app.showallCheckBox.Value;
            
            app.oriCheckBox.Value = value;
            app.isometricCheckBox.Value = value;
            app.imageLinesCheckBox.Value = value;
            app.corrValueCheckBox.Value = value;
            app.projectColorCheckBox.Value = value;
            app.projectImgCheckBox.Value = value;
            app.plot3dCheckBox.Value = value;
        end

        % Value changed function: corrValueCheckBox, 
        % imageLinesCheckBox, isometricCheckBox, oriCheckBox, 
        % plot3dCheckBox, projectColorCheckBox, projectImgCheckBox
        function anotherCheckBox(app, event)
            app.oriCheckBox.Value;
            if app.oriCheckBox.Value == 1 && ...
                app.isometricCheckBox.Value == 1 && ...
                app.imageLinesCheckBox.Value == 1 && ...
                app.corrValueCheckBox.Value == 1 && ...
                app.projectImgCheckBox.Value == 1 && ...
                app.projectColorCheckBox.Value == 1 && ...
                app.plot3dCheckBox.Value == 1
            
                app.showallCheckBox.Value = 1;
            else
                app.showallCheckBox.Value = 0;
            end
        end

        % Button pushed function: AddforComparisonButton
        function addTab(app, event)
            tabData = getappdata(app.TabGroup.SelectedTab, 'tabId');
            tabHeader = app.TabGroup.SelectedTab.Title;
            if tabData == 1
                return;
            end
            
            [x, y] = size(app.CompareListListBox.Items);
            for i=1:x
                for j=1:y
                    if app.CompareListListBox.ItemsData{i,j} == tabData
                        return;
                    end
                end
            end
            
            idx = getappdata(app.CompareListListBox, 'cnt');
            app.CompareListListBox.Items{1,idx} = tabHeader;
            app.CompareListListBox.ItemsData{1,idx} = tabData;
            setappdata(app.CompareListListBox, 'cnt', idx + 1);
        end

        % Button pushed function: CompareButton
        function compareTab(app, event)
            if isempty(app.CompareListListBox.Items)
                msgbox('Choose any comparable tab first');
                return;
            end
            dir = getappdata(app.UIFigure, 'urlNow');
            
            compareItems(dir, app.CompareListListBox);
            
            app.CompareListListBox.Items = {};
            setappdata(app.CompareListListBox, 'cnt', 1);
        end

        % Button pushed function: ClearListButton
        function clearList(app, event)
            app.CompareListListBox.Items = {};
            setappdata(app.CompareListListBox, 'cnt', 1);
        end

        % Callback function
        function unselect(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.HTML = uihtml(app.HelpTab);
            app.HTML.HTMLSource = '''''';
            app.OriginalImageTab = uitab(app.TabGroup);
            app.OriginalImageTab.Title = 'Original Image';
            app.IsometricColorTab = uitab(app.TabGroup);
            app.IsometricColorTab.Title = 'Isometric Color';
            app.ImageLinesTab = uitab(app.TabGroup);
            app.ImageLinesTab.Title = 'Image Lines';
            app.CorrelationValueTab = uitab(app.TabGroup);
            app.CorrelationValueTab.Title = 'Correlation Value';
            app.ProjectedImageTab = uitab(app.TabGroup);
            app.ProjectedImageTab.Title = 'Projected Image';
            app.ProjectedColorTab = uitab(app.TabGroup);
            app.ProjectedColorTab.Title = 'Projected Color';
            app.DPlotTab = uitab(app.TabGroup);
            app.DPlotTab.Title = '3D Plot';
            app.CompareListListBoxLabel = uilabel(app.UIFigure);
            app.CompareListListBoxLabel.Text = 'Compare List';
            app.CompareListListBox = uilistbox(app.UIFigure);
            app.CompareListListBox.Items = {};
            app.CompareListListBox.Value = {};
            app.AddforComparisonButton = uibutton(app.UIFigure, 'push');
            app.AddforComparisonButton.ButtonPushedFcn = createCallbackFcn(app, @addTab, true);
            app.AddforComparisonButton.Text = {'Add for'; 'Comparison'};
            app.CompareButton = uibutton(app.UIFigure, 'push');
            app.CompareButton.ButtonPushedFcn = createCallbackFcn(app, @compareTab, true);
            app.CompareButton.Text = 'Compare';
            app.ControlsPanel = uipanel(app.UIFigure);
            app.ControlsPanel.Title = 'Controls';
            app.btn = uibutton(app.ControlsPanel, 'push');
            app.btn.ButtonPushedFcn = createCallbackFcn(app, @buttonCallbacks, true);
            app.btn.Text = 'Process';
            app.oriCheckBox = uicheckbox(app.ControlsPanel);
            app.oriCheckBox.ValueChangedFcn = createCallbackFcn(app, @anotherCheckBox, true);
            app.oriCheckBox.Text = 'show original image';
            app.oriCheckBox.Value = true;
            app.isometricCheckBox = uicheckbox(app.ControlsPanel);
            app.isometricCheckBox.ValueChangedFcn = createCallbackFcn(app, @anotherCheckBox, true);
            app.isometricCheckBox.Text = 'show isometric color';
            app.isometricCheckBox.Value = true;
            app.imageLinesCheckBox = uicheckbox(app.ControlsPanel);
            app.imageLinesCheckBox.ValueChangedFcn = createCallbackFcn(app, @anotherCheckBox, true);
            app.imageLinesCheckBox.Text = 'show image lines';
            app.imageLinesCheckBox.Value = true;
            app.ChooseFileButton = uibutton(app.ControlsPanel, 'push');
            app.ChooseFileButton.ButtonPushedFcn = createCallbackFcn(app, @chooseFile, true);
            app.ChooseFileButton.Text = 'Choose File';
            app.corrValueCheckBox = uicheckbox(app.ControlsPanel);
            app.corrValueCheckBox.ValueChangedFcn = createCallbackFcn(app, @anotherCheckBox, true);
            app.corrValueCheckBox.Text = {'show pattern'; 'correlation value'};
            app.corrValueCheckBox.Value = true;
            app.showallCheckBox = uicheckbox(app.ControlsPanel);
            app.showallCheckBox.ValueChangedFcn = createCallbackFcn(app, @checkedAll, true);
            app.showallCheckBox.Text = 'show all';
            app.showallCheckBox.Value = true;
            app.projectImgCheckBox = uicheckbox(app.ControlsPanel);
            app.projectImgCheckBox.ValueChangedFcn = createCallbackFcn(app, @anotherCheckBox, true);
            app.projectImgCheckBox.Text = 'show projected image';
            app.projectImgCheckBox.Value = true;
            app.projectColorCheckBox = uicheckbox(app.ControlsPanel);
            app.projectColorCheckBox.ValueChangedFcn = createCallbackFcn(app, @anotherCheckBox, true);
            app.projectColorCheckBox.Text = 'show projected color';
            app.projectColorCheckBox.Value = true;
            app.plot3dCheckBox = uicheckbox(app.ControlsPanel);
            app.plot3dCheckBox.ValueChangedFcn = createCallbackFcn(app, @anotherCheckBox, true);
            app.plot3dCheckBox.Text = 'show 3D plot';
            app.plot3dCheckBox.Value = true;
            app.RubikLocationEditFieldLabel = uilabel(app.ControlsPanel);
            app.RubikLocationEditFieldLabel.Text = 'Rubik Location';
            app.loc = uieditfield(app.ControlsPanel, 'text');
            app.loc.Editable = 'off';
            app.ClearListButton = uibutton(app.UIFigure, 'push');
            app.ClearListButton.ButtonPushedFcn = createCallbackFcn(app, @clearList, true);
            app.ClearListButton.Text = 'Clear List';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startUp)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end