classdef app2017 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        oriTab                       matlab.ui.container.Tab
        HTML                         matlab.ui.control.HTML
        Tab3                         matlab.ui.container.Tab
        CompareListListBoxLabel      matlab.ui.control.Label
        CompareListListBox           matlab.ui.control.ListBox
        AddforComparisonButton       matlab.ui.control.Button
        CompareButton                matlab.ui.control.Button
        ControlsPanel                matlab.ui.container.Panel
        btn                          matlab.ui.control.Button
        oriCheckBox                  matlab.ui.control.CheckBox
        isometricCheckBox            matlab.ui.control.CheckBox
        imageLinesCheckBox           matlab.ui.control.CheckBox
        ChooseFileButton             matlab.ui.control.Button
        corrValueCheckBox            matlab.ui.control.CheckBox
        showallCheckBox              matlab.ui.control.CheckBox
        projectImgCheckBox           matlab.ui.control.CheckBox
        projectColorCheckBox         matlab.ui.control.CheckBox
        plot3dCheckBox               matlab.ui.control.CheckBox
        RubikLocationEditFieldLabel  matlab.ui.control.Label
        loc                          matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startUp(app)
            app.HTML.HTMLSource = join(["<div style='margin:6%'>"...
                                        "    <h1>Let's plot it</h1><br>"...
                                        "    <b>first</b>, choose your rubik's image.‍<br>"...
                                        "    <br>"...
                                        "    <b>Second</b>, choose any option.<br>"...
                                        "     - <b>Isometric color</b>, it'll show color segmentation from your image and display it like your original image.<br>"...
                                        "     - <b>Image lines</b>, it'll show lines of image. The lines usefull for side-by-side segmentation.<br>"...
                                        "     - <b>Pattern correlation value</b>, it'll show surface value of the image that correlate againts some features.<br>"...
                                        "     - <b>Projected image</b>, it'll show each side of cube that already projected into cube nets.<br>"...
                                        "     - <b>Projected color</b>, it'll show recognize color on each side of a cube and form a cube nets.<br>"...
                                        "     - <b>3D plot</b>, it'll show scatter plot of recognize color in 3D plot.‍<br>"...
                                        "    <br>"...
                                        "    <b>Third</b>, press button ""<b>process</b>"".<br><br>"...
                                        "    <br>"...
                                        "    *if you need to compare several result, please use ""<b>compare</b>"" features below."...
                                        "</div>"]);
        end

        % Button pushed function: btn
        function buttonCallbacks(app, event)
            %directory handler
            dir = getappdata(app.ControlsPanel, 'urls');
            if dir == ""
                msgbox('Choose any file first');
                return;
            end
            
            %checkbox handler
            checkBoxVal.oriCheckBox = app.oriCheckBox.Value;
            checkBoxVal.isometricCheckBox = app.isometricCheckBox.Value;
            checkBoxVal.imageLinesCheckBox = app.imageLinesCheckBox.Value;
            checkBoxVal.corrValueCheckBox = app.corrValueCheckBox.Value;
            checkBoxVal.projectColorCheckBox = app.projectColorCheckBox.Value;
            checkBoxVal.projectImgCheckBox = app.projectImgCheckBox.Value;
            checkBoxVal.plot3dCheckBox = app.plot3dCheckBox.Value;
            checkBoxVal.cnt = 0;
            checkBoxVal.cnt = checkBoxVal.cnt + app.oriCheckBox.Value;
            checkBoxVal.cnt = checkBoxVal.cnt + app.isometricCheckBox.Value;
            checkBoxVal.cnt = checkBoxVal.cnt + app.imageLinesCheckBox.Value;
            checkBoxVal.cnt = checkBoxVal.cnt + app.corrValueCheckBox.Value;
            checkBoxVal.cnt = checkBoxVal.cnt + app.projectColorCheckBox.Value;
            checkBoxVal.cnt = checkBoxVal.cnt + app.projectImgCheckBox.Value;
            checkBoxVal.cnt = checkBoxVal.cnt + app.plot3dCheckBox.Value;
            
            if checkBoxVal.cnt == 0
                msgbox('Choose any show option first');
                return;
            end
            
            processImage(dir, checkBoxVal, app.TabGroup, tabList);
        end

        % Button pushed function: ChooseFileButton
        function chooseFile(app, event)
            [fileName, path] = uigetfile('*.jpg;*.png;*.bmp');
            figure(app.UIFigure);
            
            app.loc.Value = fileName;
            setappdata(app.ControlsPanel, 'urls', fullfile(path, fileName));
        end

        % Value changed function: showallCheckBox
        function checkedAll(app, event)
            value = app.showallCheckBox.Value;
            
            app.oriCheckBox.Value = value;
            app.isometricCheckBox.Value = value;
            app.imageLinesCheckBox.Value = value;
            app.corrValueCheckBox.Value = value;
            app.projectColorCheckBox.Value = value;
            app.projectImgCheckBox.Value = value;
            app.plot3dCheckBox.Value = value;
        end

        % Value changed function: corrValueCheckBox, 
        % imageLinesCheckBox, isometricCheckBox, oriCheckBox, 
        % plot3dCheckBox, projectColorCheckBox, projectImgCheckBox
        function anotherCheckBox(app, event)
            app.oriCheckBox.Value;
            if app.oriCheckBox.Value == 1 && ...
                app.isometricCheckBox.Value == 1 && ...
                app.imageLinesCheckBox.Value == 1 && ...
                app.corrValueCheckBox.Value == 1 && ...
                app.projectImgCheckBox.Value == 1 && ...
                app.projectColorCheckBox.Value == 1 && ...
                app.plot3dCheckBox.Value == 1
            
                app.showallCheckBox.Value = 1;
            else
                app.showallCheckBox.Value = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.oriTab = uitab(app.TabGroup);
            app.oriTab.Title = 'Help';
            app.HTML = uihtml(app.oriTab);
            app.HTML.HTMLSource = '''''';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.CompareListListBoxLabel = uilabel(app.UIFigure);
            app.CompareListListBoxLabel.Text = 'Compare List';
            app.CompareListListBox = uilistbox(app.UIFigure);
            app.AddforComparisonButton = uibutton(app.UIFigure, 'push');
            app.AddforComparisonButton.Text = {'Add for'; 'Comparison'};
            app.CompareButton = uibutton(app.UIFigure, 'push');
            app.CompareButton.Text = 'Compare';
            app.ControlsPanel = uipanel(app.UIFigure);
            app.ControlsPanel.Title = 'Controls';
            app.btn = uibutton(app.ControlsPanel, 'push');
            app.btn.ButtonPushedFcn = createCallbackFcn(app, @buttonCallbacks, true);
            app.btn.Text = 'Process';
            app.oriCheckBox = uicheckbox(app.ControlsPanel);
            app.oriCheckBox.ValueChangedFcn = createCallbackFcn(app, @anotherCheckBox, true);
            app.oriCheckBox.Text = 'show original image';
            app.oriCheckBox.Value = true;
            app.isometricCheckBox = uicheckbox(app.ControlsPanel);
            app.isometricCheckBox.ValueChangedFcn = createCallbackFcn(app, @anotherCheckBox, true);
            app.isometricCheckBox.Text = 'show isometric color';
            app.isometricCheckBox.Value = true;
            app.imageLinesCheckBox = uicheckbox(app.ControlsPanel);
            app.imageLinesCheckBox.ValueChangedFcn = createCallbackFcn(app, @anotherCheckBox, true);
            app.imageLinesCheckBox.Text = 'show image lines';
            app.imageLinesCheckBox.Value = true;
            app.ChooseFileButton = uibutton(app.ControlsPanel, 'push');
            app.ChooseFileButton.ButtonPushedFcn = createCallbackFcn(app, @chooseFile, true);
            app.ChooseFileButton.Text = 'Choose File';
            app.corrValueCheckBox = uicheckbox(app.ControlsPanel);
            app.corrValueCheckBox.ValueChangedFcn = createCallbackFcn(app, @anotherCheckBox, true);
            app.corrValueCheckBox.Text = {'show pattern'; 'correlation value'};
            app.corrValueCheckBox.Value = true;
            app.showallCheckBox = uicheckbox(app.ControlsPanel);
            app.showallCheckBox.ValueChangedFcn = createCallbackFcn(app, @checkedAll, true);
            app.showallCheckBox.Text = 'show all';
            app.showallCheckBox.Value = true;
            app.projectImgCheckBox = uicheckbox(app.ControlsPanel);
            app.projectImgCheckBox.ValueChangedFcn = createCallbackFcn(app, @anotherCheckBox, true);
            app.projectImgCheckBox.Text = 'show projected image';
            app.projectImgCheckBox.Value = true;
            app.projectColorCheckBox = uicheckbox(app.ControlsPanel);
            app.projectColorCheckBox.ValueChangedFcn = createCallbackFcn(app, @anotherCheckBox, true);
            app.projectColorCheckBox.Text = 'show projected color';
            app.projectColorCheckBox.Value = true;
            app.plot3dCheckBox = uicheckbox(app.ControlsPanel);
            app.plot3dCheckBox.ValueChangedFcn = createCallbackFcn(app, @anotherCheckBox, true);
            app.plot3dCheckBox.Text = 'show 3D plot';
            app.plot3dCheckBox.Value = true;
            app.RubikLocationEditFieldLabel = uilabel(app.ControlsPanel);
            app.RubikLocationEditFieldLabel.Text = 'Rubik Location';
            app.loc = uieditfield(app.ControlsPanel, 'text');
            app.loc.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2017

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startUp)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end